classdef my1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DemoPanel             matlab.ui.container.Panel
        Filter1DropDownLabel  matlab.ui.control.Label
        Filter1DropDown       matlab.ui.control.DropDown
        Filter2DropDownLabel  matlab.ui.control.Label
        Filter2DropDown       matlab.ui.control.DropDown
        Filter3DropDownLabel  matlab.ui.control.Label
        Filter3DropDown       matlab.ui.control.DropDown
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        ChooseImage1Button    matlab.ui.control.Button
        ChooseImage2Button    matlab.ui.control.Button
        ChooseImage3Button    matlab.ui.control.Button
        CropCheckBox          matlab.ui.control.CheckBox
        SaveasmatfileLabel    matlab.ui.control.Label
        SaveEditField         matlab.ui.control.EditField
        ProcessButton         matlab.ui.control.Button
        bgdCheckBox           matlab.ui.control.CheckBox
        LookupTableGen        matlab.ui.control.Button
        BoyiLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        bgd_1 = 0; % background value
        bgd_2 = 0;
        bgd_3 = 0;
        image_1 = 0;
        image_2 = 0;
        image_3 = 0;
        lookupTable = 0;
        lookupTableName = 0;
    end
    
    methods (Access = public)
        
        function process(app, outputFileName)
            luTable = load(app.lookupTableName);
            % lookupTable is a matrix
            
            t1data = app.image_1 - app.bgd_1;
            t1data(t1data < 0) = 0;
            
            t2data = app.image_2 - app.bgd_2;
            t2data(t2data < 0) = 0;
            
            t3data = app.image_3 - app.bgd_3;
            t3data(t3data < 0) = 0;
            
            if size(t1data) ~= size(t2data) & size(t1data) ~= size(t3data)
                error('Dimension not match\n');
            end
            
            r1_2 = t1data ./ t2data;
            r1_3 = t1data ./ t3data;
            result_FWHM = zeros(size(t1data,1),size(t1data,2));
            result_lambda = zeros(size(t1data,1),size(t1data,2));
            n = size(luTable.lookupTable,1);
            
            
            for i = 1:1:size(result_FWHM,1)*size(result_FWHM,2)
                if mod(i,10000) == 0
                    fprintf('i = %d\n',i)
                end
                
                ir1_2 = ones(n,1) * double(r1_2(i));
                ir1_3 = ones(n,1) * double(r1_3(i));
                dr1_2 = abs(ir1_2 - luTable.lookupTable(:,6));
                dr1_3 = abs(ir1_3 - luTable.lookupTable(:,7));
            
                sumd = dr1_2 + dr1_3;
            
                [~, index] = min(sumd);
                result_FWHM(i) = luTable.lookupTable(index, 1);
                result_lambda(i) = luTable.lookupTable(index, 2);
            end
            
            save([outputFileName,'.mat'],'result_FWHM','result_lambda');
            disp('Simulation finished');
        end
    end


    methods (Access = private)

        % Button pushed function: ChooseImage1Button
        function ChooseImage1ButtonPushed(app, event)
            [file, path1] = uigetfile('*.*');
            path1 = [path1, file];
            if path1 ~= 0  
                im1 = imread(path1);
                imshow(im1, 'Parent', app.UIAxes); % show the image
                app.UIAxes.UserData = im1; % store image data
            end
        end

        % Button pushed function: ChooseImage2Button
        function ChooseImage2ButtonPushed(app, event)
            [file, path2] = uigetfile('*.*');
            path2 = [path2, file];
            if path2 ~= 0
                im2 = imread(path2);
                imshow(im2, 'Parent', app.UIAxes_2);
                app.UIAxes_2.UserData = im2;
            end
        end

        % Button pushed function: ChooseImage3Button
        function ChooseImage3ButtonPushed(app, event)
            [file, path3] = uigetfile('*.*');
            path3 = [path3, file];
            if path3 ~= 0
                im3 = imread(path3);
                imshow(im3, 'Parent', app.UIAxes_3);
                app.UIAxes_3.UserData = im3;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            val_bgd = app.bgdCheckBox.Value;
            val_crop = app.CropCheckBox.Value;
            
            % default without cropping and removing background
            app.image_1 = app.UIAxes.UserData;
            app.image_2 = app.UIAxes_2.UserData;
            app.image_3 = app.UIAxes_3.UserData;    
            
            if val_bgd == 1 % choose background area to remove background noise
                f = msgbox('Choose background area for averaging background noise');
                pause(3);
                if isvalid(f) == 1
                    close(f);   
                end
                [~, rect] = imcrop(app.UIAxes.UserData);
                close;
                rect = round(rect);
                
                % calculate background value for each image
                app.bgd_1 = app.UIAxes.UserData(rect(2):rect(2)+rect(4), ...
                     rect(1):rect(1)+rect(3));
                app.bgd_1 = sum(sum(app.bgd_1))/(size(app.bgd_1,1)*size(app.bgd_1,2));
                
                app.bgd_2 = app.UIAxes_2.UserData(rect(2):rect(2)+rect(4), ...
                     rect(1):rect(1)+rect(3));
                app.bgd_2 = sum(sum(app.bgd_2))/(size(app.bgd_2,1)*size(app.bgd_2,2));
                
                app.bgd_3 = app.UIAxes_3.UserData(rect(2):rect(2)+rect(4), ...
                     rect(1):rect(1)+rect(3));
                app.bgd_3 = sum(sum(app.bgd_3))/(size(app.bgd_3,1)*size(app.bgd_3,2));
            end
            
            if val_crop == 1 % crop the image
                f = msgbox('Crop the image (only once)');
                pause(3);
                if isvalid(f) == 1
                    close(f);   
                end
                [~, rect] = imcrop(app.UIAxes.UserData); 
                close;
                rect = round(rect);
                app.image_1 = app.UIAxes.UserData(rect(2):rect(2)+rect(4), ...
                     rect(1):rect(1)+rect(3));
                imshow(app.image_1, 'Parent', app.UIAxes)
                app.image_2 = app.UIAxes_2.UserData(rect(2):rect(2)+rect(4), ...
                     rect(1):rect(1)+rect(3));
                imshow(app.image_2, 'Parent', app.UIAxes_2)
                app.image_3 = app.UIAxes_3.UserData(rect(2):rect(2)+rect(4), ...
                     rect(1):rect(1)+rect(3));
                imshow(app.image_3, 'Parent', app.UIAxes_3)
            end
            
            % process begins
            filter1 = [app.Filter1DropDown.Value([1:3,end-1:end]), '.txt'];
            filter2 = [app.Filter2DropDown.Value([1:3,end-1:end]), '.txt'];
            filter3 = [app.Filter3DropDown.Value([1:3,end-1:end]), '.txt'];
            app.lookupTableName = ['lu_',filter1(1:5),'_',filter2(1:5),'_',...
                filter3(1:5),'.mat'];
            
            disp(app.lookupTableName)
            
            if exist(app.lookupTableName) ~= 2
                LookupTableGenPushed(app);
            end
            
            outputFileName = app.SaveEditField.Value;
            
            process(app, outputFileName);
                
                
            %output = process(app, app.SaveEditField.Value);
        end

        % Button pushed function: LookupTableGen
        function LookupTableGenPushed(app, event)
            % load lookup table
            filter1 = [app.Filter1DropDown.Value([1:3,end-1:end]), '.txt'];
            filter2 = [app.Filter2DropDown.Value([1:3,end-1:end]), '.txt'];
            filter3 = [app.Filter3DropDown.Value([1:3,end-1:end]), '.txt'];
            
            dataCamera = fliplr(dlmread('Camera.txt','\t')');

            dataf1 = fliplr(dlmread(filter1,'\t')');
            dataf1(dataf1 < 0) = 0;
            lambda1 = dataf1;
            lambda1(2,:) = 0;
            
            dataf2 = fliplr(dlmread(filter2,'\t')');
            dataf2(dataf2 < 0) = 0;
            lambda2 = dataf2;
            lambda2(2,:) = 0;
            
            dataf3 = fliplr(dlmread(filter3,'\t')');
            dataf3(dataf3 < 0) = 0;
            lambda3 = dataf3;
            lambda3(2,:) = 0;
            
            FWHM = 1:0.5:70;
            lambda_miu = 600:1:800;
            
            app.lookupTable = ones(size(FWHM,2)*size(lambda_miu,2),7);
            
            for i = 1:1:size(FWHM,2)
                for j = 1:1:size(lambda_miu,2)
                    lambda1(2,:) = 2*sqrt(2*log(2))/(FWHM(i)*sqrt(2*pi))*exp(-4*log(2)* ...
                        (lambda1(1,:)-lambda_miu(j)).^2/(FWHM(i))^2);
                    lambda1(2,:) = lambda1(2,:) .* dataf1(2,:) .* dataCamera(2,:);
                    
                    lambda2(2,:) = 2*sqrt(2*log(2))/(FWHM(i)*sqrt(2*pi))*exp(-4*log(2)* ...
                        (lambda2(1,:)-lambda_miu(j)).^2/(FWHM(i))^2);
                    lambda2(2,:) = lambda2(2,:) .* dataf2(2,:) .* dataCamera(2,:);
                    
                    lambda3(2,:) = 2*sqrt(2*log(2))/(FWHM(i)*sqrt(2*pi))*exp(-4*log(2)* ...
                        (lambda3(1,:)-lambda_miu(j)).^2/(FWHM(i))^2);
                    lambda3(2,:) = lambda3(2,:) .* dataf3(2,:) .* dataCamera(2,:);
                    %lookupTable(i*j,:) = [FWHM(i),lambda_miu(j), PL1_lambda];
                    intensity1 = trapz(lambda1(2,:));
                    intensity2 = trapz(lambda2(2,:));
                    intensity3 = trapz(lambda3(2,:));
                    app.lookupTable((i-1)*size(lambda_miu,2)+j,:) = [FWHM(i), lambda_miu(j), ...
                        intensity1, intensity2, intensity3, ...
                        intensity1/intensity2, intensity1/intensity3];
                end
            end % end of for loop
            
            lookupTable = app.lookupTable; % variable name for save
            
            app.lookupTableName = ['lu_',filter1(1:5),'_',filter2(1:5),'_',...
                filter3(1:5),'.mat'];
            % app.lookupTableName = lookupTableName;
            save(app.lookupTableName, 'lookupTable');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DemoPanel = uipanel(app.UIFigure);
            app.DemoPanel.Title = 'Demo';
            app.Filter1DropDownLabel = uilabel(app.DemoPanel);
            app.Filter1DropDownLabel.Text = 'Filter 1';
            app.Filter1DropDown = uidropdown(app.DemoPanel);
            app.Filter1DropDown.Items = {'675nm LP', '700nm LP', '725nm LP', '750nm LP', '775nm LP', '800nm LP', '700nm SP', '725nm SP', '750nm SP', '775nm SP', '800nm SP'};
            app.Filter1DropDown.Value = '675nm LP';
            app.Filter2DropDownLabel = uilabel(app.DemoPanel);
            app.Filter2DropDownLabel.Text = 'Filter 2';
            app.Filter2DropDown = uidropdown(app.DemoPanel);
            app.Filter2DropDown.Items = {'675nm LP', '700nm LP', '725nm LP', '750nm LP', '775nm LP', '800nm LP', '700nm SP', '725nm SP', '750nm SP', '775nm SP', '800nm SP'};
            app.Filter2DropDown.Value = '675nm LP';
            app.Filter3DropDownLabel = uilabel(app.DemoPanel);
            app.Filter3DropDownLabel.Text = 'Filter 3';
            app.Filter3DropDown = uidropdown(app.DemoPanel);
            app.Filter3DropDown.Items = {'675nm LP', '700nm LP', '725nm LP', '750nm LP', '775nm LP', '800nm LP', '700nm SP', '725nm SP', '750nm SP', '775nm SP', '800nm SP'};
            app.Filter3DropDown.Value = '675nm LP';
            app.UIAxes = uiaxes(app.DemoPanel);
            app.UIAxes_2 = uiaxes(app.DemoPanel);
            app.UIAxes_3 = uiaxes(app.DemoPanel);
            app.ChooseImage1Button = uibutton(app.DemoPanel, 'push');
            app.ChooseImage1Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseImage1ButtonPushed, true);
            app.ChooseImage1Button.Text = 'Choose Image 1';
            app.ChooseImage2Button = uibutton(app.DemoPanel, 'push');
            app.ChooseImage2Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseImage2ButtonPushed, true);
            app.ChooseImage2Button.Text = 'Choose Image 2';
            app.ChooseImage3Button = uibutton(app.DemoPanel, 'push');
            app.ChooseImage3Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseImage3ButtonPushed, true);
            app.ChooseImage3Button.Text = 'Choose Image 3';
            app.CropCheckBox = uicheckbox(app.DemoPanel);
            app.CropCheckBox.Text = 'Crop image (for faster process)';
            app.SaveasmatfileLabel = uilabel(app.DemoPanel);
            app.SaveasmatfileLabel.Text = 'Save as (mat file)';
            app.SaveEditField = uieditfield(app.DemoPanel, 'text');
            app.SaveEditField.Value = 'result';
            app.ProcessButton = uibutton(app.DemoPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.bgdCheckBox = uicheckbox(app.DemoPanel);
            app.bgdCheckBox.Text = 'Remove (average) background noise';
            app.LookupTableGen = uibutton(app.DemoPanel, 'push');
            app.LookupTableGen.ButtonPushedFcn = createCallbackFcn(app, @LookupTableGenPushed, true);
            app.LookupTableGen.Text = {'Generate Lookup Table'; 'ratio1 = filter1/filter2'; 'ratio2 = filter1/filter3'};
            app.BoyiLabel = uilabel(app.DemoPanel);
            app.BoyiLabel.Text = 'ⒸBoyi';
        end
    end

    methods (Access = public)

        % Construct app
        function app = my1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end